classdef receiver_real < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        windowlengthEditField         matlab.ui.control.NumericEditField
        windowlengthEditFieldLabel    matlab.ui.control.Label
        overlapEditField              matlab.ui.control.NumericEditField
        overlapEditFieldLabel         matlab.ui.control.Label
        fftlengthEditField            matlab.ui.control.NumericEditField
        fftlengthEditFieldLabel       matlab.ui.control.Label
        DurationEditField_2           matlab.ui.control.NumericEditField
        DurationEditField_2Label      matlab.ui.control.Label
        CategoryEditField             matlab.ui.control.NumericEditField
        CategoryEditFieldLabel        matlab.ui.control.Label
        DurationEditField             matlab.ui.control.NumericEditField
        DurationEditFieldLabel        matlab.ui.control.Label
        Decoded_MessageTextArea       matlab.ui.control.TextArea
        Decoded_MessageTextAreaLabel  matlab.ui.control.Label
        MFSKEditField                 matlab.ui.control.NumericEditField
        MFSKEditFieldLabel            matlab.ui.control.Label
        SamplingFrequencyEditField    matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        RecordButton                  matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            Th = 1; 
            Delta_f = 100; 
            n = app.MFSKEditField.Value; 
            Fs = app.SamplingFrequencyEditField.Value; 

            category = app.CategoryEditField.Value;
     
            if category == 1
                fk = [1200, 1700, 2200, 2700, 3200, 3700, 4200, 4700, 5200];
                Th = 1; 
                Delta_f = 100; 
                n=1;
                
            end
            if category == 2
                fk = [1300, 2300, 3300, 4300, 5300, 6300, 7300, 8300];
                Th = 0.75; 
                Delta_f = 150;
                n=2;
            end
            if category == 3
                fk = [1200, 3200, 5200, 7200, 9200, 11200];
                Th = 0.5; 
                Delta_f = 200; 
                n=3;
            end
            recObj = audiorecorder(Fs, 16, 1);


            app.Decoded_MessageTextArea.Value = "Recording";
            recordblocking(recObj, app.DurationEditField.Value); 


            x_t = getaudiodata(recObj);
            
            app.Decoded_MessageTextArea.Value = "Processing...";
            

            [s, f, t_stft] = stft(x_t, Fs, 'Window', hamming(app.windowlengthEditField.Value), 'OverlapLength', app.overlapEditField.Value , 'FFTLength', app.fftlengthEditField.Value);
            
        
            imagesc(app.UIAxes2, t_stft, f, 20*log10(abs(s)));
            set(app.UIAxes2, 'YDir', 'normal');
            axis(app.UIAxes2, 'tight');
            xlabel(app.UIAxes2, 'Time (s)');
            ylabel(app.UIAxes2, 'Frequency (Hz)');
            title(app.UIAxes2, 'Short-Time Fourier Transform of Received Signal');
            colormap(app.UIAxes2, 'jet');
            colorbar(app.UIAxes2); 
                    
        
            [s, f, t_stft] = stft(x_t, Fs, 'Window', hamming(128), 'OverlapLength', 120, 'FFTLength', 1024);

            magnitude = abs(s);
            
    
            [~, maxIndices] = max(magnitude, [], 1);
            
            dominantFrequencies = abs(f(maxIndices));
            
            plateau_freq = fk(end) + (2^(n-1)+1)*Delta_f;     
            tol = Delta_f/2;                
            min_len = Th*Fs/9;         
            
            dominantFrequencies = processDominantFrequencies(dominantFrequencies, plateau_freq, tol, min_len);
            
            closest_values = mapFreqWithoutRepeats(dominantFrequencies, fk)
            
            m_k = round((dominantFrequencies - closest_values)/Delta_f)
            
            letters = decodeFSKToLetters(m_k, n);
            app.Decoded_MessageTextArea.Value = letters;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.SamplingFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFrequencyEditField.Value = 44100;
            app.MFSKEditFieldLabel = uilabel(app.UIFigure);
            app.MFSKEditFieldLabel.Text = 'MFSK';
            app.MFSKEditField = uieditfield(app.UIFigure, 'numeric');
            app.MFSKEditField.Value = 2;
            app.Decoded_MessageTextAreaLabel = uilabel(app.UIFigure);
            app.Decoded_MessageTextAreaLabel.Text = 'Decoded_Message';
            app.Decoded_MessageTextArea = uitextarea(app.UIFigure);
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField.Value = 20;
            app.CategoryEditFieldLabel = uilabel(app.UIFigure);
            app.CategoryEditFieldLabel.Text = 'Category';
            app.CategoryEditField = uieditfield(app.UIFigure, 'numeric');
            app.CategoryEditField.Value = 2;
            app.DurationEditField_2Label = uilabel(app.UIFigure);
            app.DurationEditField_2Label.Text = 'Duration';
            app.DurationEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField_2.Value = 20;
            app.fftlengthEditFieldLabel = uilabel(app.UIFigure);
            app.fftlengthEditFieldLabel.Text = 'fftlength';
            app.fftlengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.fftlengthEditField.Value = 256;
            app.overlapEditFieldLabel = uilabel(app.UIFigure);
            app.overlapEditFieldLabel.Text = 'overlap';
            app.overlapEditField = uieditfield(app.UIFigure, 'numeric');
            app.overlapEditField.Value = 120;
            app.windowlengthEditFieldLabel = uilabel(app.UIFigure);
            app.windowlengthEditFieldLabel.Text = 'windowlength';
            app.windowlengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.windowlengthEditField.Value = 128;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = receiver_real

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end