classdef transmitter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        winLengthEditFieldLabel     matlab.ui.control.Label
        winLengthEditField          matlab.ui.control.NumericEditField
        overlapEditFieldLabel       matlab.ui.control.Label
        fftlengthEditFieldLabel     matlab.ui.control.Label
        overlapEditField            matlab.ui.control.NumericEditField
        fftlengthEditField          matlab.ui.control.NumericEditField
        CategoryEditField           matlab.ui.control.NumericEditField
        CategoryEditFieldLabel      matlab.ui.control.Label
        PlayButton                  matlab.ui.control.Button
        delFEditField               matlab.ui.control.NumericEditField
        delFEditFieldLabel          matlab.ui.control.Label
        HopPeriodEditField          matlab.ui.control.NumericEditField
        HopPeriodEditFieldLabel     matlab.ui.control.Label
        MFSKEditField               matlab.ui.control.NumericEditField
        MFSKEditFieldLabel          matlab.ui.control.Label
        CreateButton                matlab.ui.control.Button
        MessageEditField            matlab.ui.control.EditField
        MessageEditFieldLabel       matlab.ui.control.Label
        SamplingFrequencyEditField  matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)

            n = log2(app.MFSKEditField.Value);  
            Fs = app.SamplingFrequencyEditField.Value;  
            Th = app.HopPeriodEditField.Value;
            category = app.CategoryEditField.Value;
            Delta_f = app.delFEditField.Value;
            m_k_str = app.MessageEditField.Value;
            
            if category == 1
                fk = [1200, 1700, 2200, 2700, 3200, 3700, 4200, 4700, 5200];
                Th = 1; 
                Delta_f = 100; 
                n=1;
            end
            if category == 2
                fk = [1300, 2300, 3300, 4300, 5300, 6300, 7300, 8300];
                Th = 0.75; 
                Delta_f = 150; 
                n=2;
            end
            if category == 3
                fk = [1200, 3200, 5200, 7200, 9200, 11200];
                Th = 0.5; 
                Delta_f = 200;
                n=3;
            end
            min_sep = 250*2^n;
           
            m_k = stringToFSK(m_k_str, n);
            
            [x_t, t] = generateFSKSignal(m_k, Fs, Th, fk, Delta_f, min_sep, n);
            
            audiowrite('audio.wav', real(x_t), Fs);

%[s, f, t_stft] = stft(x_t, Fs, 'Window', hamming(128), 'OverlapLength', 120, 'FFTLength', 1024);


            winLength = app.winLengthEditField.Value;
            overlap = app.overlapEditField.Value;
            fftLength = app.fftlengthEditField.Value;
            
            
            [s, f, t_stft] = stft(real(x_t), Fs, 'Window', hamming(winLength, 'periodic'), ...
                                  'OverlapLength', overlap, 'FFTLength', fftLength);
        
    
            surf(app.UIAxes, t_stft, f, 20*log10(abs(s)), 'EdgeColor', 'none');
            axis(app.UIAxes, 'tight');
            view(app.UIAxes, 0, 90);
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Frequency (Hz)');
            title(app.UIAxes, 'Short-Time Fourier Transform');
            colormap(app.UIAxes, 'jet');
            colorbar(app.UIAxes);


        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            [y, Fs] = audioread("audio.wav");
            player = audioplayer(y, Fs);
            play(player);
            waitfor(player);
            
        end

        % Callback function
        function UIFigureButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SamplingFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFrequencyEditField.Value = 44100;
            app.MessageEditFieldLabel = uilabel(app.UIFigure);
            app.MessageEditFieldLabel.Text = 'Message';
            app.MessageEditField = uieditfield(app.UIFigure, 'text');
            app.MessageEditField.Value = 'hello';
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.MFSKEditFieldLabel = uilabel(app.UIFigure);
            app.MFSKEditFieldLabel.Text = 'M-FSK';
            app.MFSKEditField = uieditfield(app.UIFigure, 'numeric');
            app.MFSKEditField.Value = 4;
            app.HopPeriodEditFieldLabel = uilabel(app.UIFigure);
            app.HopPeriodEditFieldLabel.Text = 'Hop Period';
            app.HopPeriodEditField = uieditfield(app.UIFigure, 'numeric');
            app.HopPeriodEditField.Value = 0.75;
            app.delFEditFieldLabel = uilabel(app.UIFigure);
            app.delFEditFieldLabel.Text = 'del-F';
            app.delFEditField = uieditfield(app.UIFigure, 'numeric');
            app.delFEditField.Value = 150;
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.CategoryEditFieldLabel = uilabel(app.UIFigure);
            app.CategoryEditFieldLabel.Text = 'Category';
            app.CategoryEditField = uieditfield(app.UIFigure, 'numeric');
            app.CategoryEditField.Value = 3;
            app.fftlengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.fftlengthEditField.Value = 128;
            app.overlapEditField = uieditfield(app.UIFigure, 'numeric');
            app.overlapEditField.Value = 120;
            app.fftlengthEditFieldLabel = uilabel(app.UIFigure);
            app.fftlengthEditFieldLabel.Text = 'fft length';
            app.overlapEditFieldLabel = uilabel(app.UIFigure);
            app.overlapEditFieldLabel.Text = 'overlap';
            app.winLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.winLengthEditField.Value = 128;
            app.winLengthEditFieldLabel = uilabel(app.UIFigure);
            app.winLengthEditFieldLabel.Text = 'winLength';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transmitter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end